classdef StewartLA3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputParametersPanel            matlab.ui.container.Panel
        ApproximationMethodButtonGroup  matlab.ui.container.ButtonGroup
        EulersMethodButton              matlab.ui.control.RadioButton
        MidpointMethodButton            matlab.ui.control.RadioButton
        hEditFieldLabel                 matlab.ui.control.Label
        hEditField                      matlab.ui.control.EditField
        x0Label                         matlab.ui.control.Label
        x0EditField                     matlab.ui.control.NumericEditField
        yx0EditFieldLabel               matlab.ui.control.Label
        yx0EditField                    matlab.ui.control.NumericEditField
        xnEditFieldLabel                matlab.ui.control.Label
        xnEditField                     matlab.ui.control.NumericEditField
        dydxEditFieldLabel              matlab.ui.control.Label
        dydxEditField                   matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        ClearPlotButton                 matlab.ui.control.Button
        ComparetoMATLABLowOrderMethodCheckBox  matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
    end


    methods (Access = private)
    
        function [yn] = compareHValues(app , method , func , h , xo , yo , xn)
            %compareHValues Runs and plots approximations for multiple values of h and executes ode23() if requested by user GUI input
            %   ~inputs~
            %   func: the ordinary differential function of x and y
            %   h: a 1 row, n column vector of the h-values to be compared
            %   xo,yo: the initial conditions for the differential equation
            %   xn: the x-value at which to approximate func
            %   method: 'euler' for euler's method or 'midpoint' for midpoint method
            %
            %   ~outputs~
            %   yn: a vecor of the same size as h (input) with the results obtained
            %   from running Euler's Method with each h value

            hold(app.UIAxes , 'on')
            plots = [];
            legendText = [];
            if strcmp(method , 'euler')
                yn = ones(1 , length(h));
                for index = 1:length(h)
                    yn(index) = eulerApproximate(app , func , h(index) , xo , yo , xn);
                    [p , lt] = solutionCurve(app , func , h(index) , xo , yo , xn , method);
                    plots(index) = p;
                    legendText = char(legendText , lt);
                end
            else
                yn = ones(1 , length(h));
                for index = 1:length(h)
                    yn(index) = midpointMethod(app , func , h(index) , xo , yo , xn);
                    [p , lt] = solutionCurve(app , func , h(index) , xo , yo , xn , method);
                    plots(index) = p;
                    legendText = char(legendText , lt);
                end
            end
            
            legendText = legendText(2:end , :);
            
            
             if app.ComparetoMATLABLowOrderMethodCheckBox.Value
                [x , y] = ode23(func , [xo , xn] , yo);
                hold(app.UIAxes , 'on');
                plots(length(plots) + 1) = plot(app.UIAxes , x , y);
                legendText = char(legendText , 'Low Order Method');
                plot(app.UIAxes , x(1) , y(1) , 'rx');
                plots(length(plots) + 1) = plot(app.UIAxes , x(length(x)) , y(length(y)) , 'gp');
                legendText = char(legendText , 'Low Order Solution');
                differentialField(app , func , xo , xn , floor(min(y)) , ceil(max(y)));
                data = app.UITable.Data;
                if length(data) >= 1
                    hVals = [data(:,1) ; -1];
                    ynVals = [data(:,2) ; y(length(y))];
                else
                    hVals = -1;
                    ynVals = y(length(y));
                end
                app.UITable.Data = [hVals,ynVals];
             end
             
             legend(app.UIAxes , plots , legendText);
            
        end

        function [p , legendText] = solutionCurve(app , func , h , xo , yo , xn , method)
            %solutionCurve Plots differential vecor field and solution curve for func, fills solution into table
            %   func: an ordinary differential function of x and y
            %   xo/yo: the initial x/y conditions for the solution
            %   xn: the value at which func is to be approximated
            if strcmp(method , 'euler')
                [yn , xvals , yvals] = eulerApproximate(app , func , h , xo , yo , xn);
            else
                [yn , xvals , yvals] = midpointMethod(app , func , h , xo , yo , xn);
            end
            hold(app.UIAxes , 'on')
            differentialField(app , func,xo,xn,floor(min(yvals)),ceil(max(yvals)))
            legendText = sprintf('h = %g' , h);
            p = plot(app.UIAxes , xvals , yvals);
            plot(app.UIAxes , xvals(1) , yvals(1) , 'rx')
            plot(app.UIAxes , xvals(length(xvals)) , yvals(length(yvals)) , 'go')
            data = app.UITable.Data;
            if length(data) >= 1
                hVals = [data(:,1) ; num2cell(h)];
                ynVals = [data(:,2) ; num2cell(yn)];
            else
                hVals = num2cell(h);
                ynVals = num2cell(yn);
            end
            app.UITable.Data = [hVals,ynVals];
        
        end
        
        function [yn , xvals , yvals] = eulerApproximate(app , func , h , xo , yo , xn)
            %eulerApproximate Uses Euler's Method to approximate a diff. eq. at given x
            %   ~inputs~
            %   -func: the differential equation. must me a function of x and y. use @
            %   form.
            %   -h: step size (scalar float)
            %   -xo: x initial condition (scalar float)
            %   -yo: y initial condition (scalar float)
            %   -xn: the x-value at which to approximate the solution to the
            %   differential equation.
            %   
            %    ~outputs~
            %    -yn: the solution to the differential equation at xn
            %    -xvals: the x values of the solution curve on [xo,xn]
            %    -yvals: the y values of the solution curve on [yo,yn]
            
            xvals = xo:h:xn;
            yvals = ones(1 , length(xvals));
            yvals(1) = yo;
            for index = 2:length(xvals)
                yvals(index) = yvals(index - 1) + h * func(xvals(index - 1) , yvals(index - 1));
            end
            yn = yvals(length(yvals));
        end
        
        function differentialField(app , func , xmin , xmax , ymin , ymax)
            %differentialField Plots a field of differential vectors for a given func
            %   func: an ordinary differential function of x and y
            %   xmin/xmax/ymin/ymax: the boundaries of where you want to plot the
            %   vector field
            xvector = linspace(xmin , xmax , 20);
            yvector = linspace(ymax , ymin , 20)';
            x = ones(length(yvector) , 1) * xvector;
            y = yvector * ones(1 , length(xvector));
            dydx = func(x , y);
            xcomponent = cos(atan(dydx));
            ycomponent = sin(atan(dydx));
            
            quiver(app.UIAxes , x , y , xcomponent , ycomponent , .2 , 'b-')
            axis(app.UIAxes , [min(min(x)) max(max(x)) min(min(y)) max(max(y))])
        end

        function [yn , xvals , yvals] = midpointMethod(app , func , h , xo , yo , xn)
            %midpointMethod Uses Midpoint Method to approximate a diff. eq. at given x
            %   ~inputs~
            %   -func: the differential equation. must me a function of x and y. use @
            %   form.
            %   -h: step size (scalar float)
            %   -xo: x initial condition (scalar float)
            %   -yo: y initial condition (scalar float)
            %   -xn: the x-value at which to approximate the solution to the
            %   differential equation.
            %   
            %    ~outputs~
            %    -yn: the solution to the differential equation at xn
            %    -xvals: the x values of the solution curve on [xo,xn]
            %    -yvals: the y values of the solution curve on [yo,yn]
            
            xvals = xo:h:xn;
            yvals = ones(1 , length(xvals));
            yvals(1) = yo;
            for index = 2:length(xvals)
                yvals(index) = yvals(index - 1) + h * func(xvals(index - 1) + .5*h, yvals(index - 1) + .5 * h * func(xvals(index - 1) , yvals(index - 1)));
            end
            yn = yvals(length(yvals));

        end
        
    end


    methods (Access = private)

        % Button pushed function: SolveButton
        function solve(app, event)
            % Retrieves all inputs from the app GUI, and calls plotting and solving functions accordingly.
            func = str2func(app.dydxEditField.Value);
            xn = app.xnEditField.Value;
            h = str2num(app.hEditField.Value);
            xo = app.x0EditField.Value;
            yo = app.yx0EditField.Value;
            if app.EulersMethodButton.Value
                method = 'euler';
            else
                method = 'midpoint';
            end
            
            compareHValues(app , method , func , h , xo , yo , xn);
                
        end

        % Button pushed function: ClearPlotButton
        function clear(app, event)
            %Clears the axes, table, and legend.
            cla(app.UIAxes)
            app.UITable.Data = [];
            legend(app.UIAxes , 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.ApproximationMethodButtonGroup = uibuttongroup(app.InputParametersPanel);
            app.ApproximationMethodButtonGroup.Title = 'Approximation Method';
            app.EulersMethodButton = uiradiobutton(app.ApproximationMethodButtonGroup);
            app.EulersMethodButton.Text = 'Euler''s Method';
            app.EulersMethodButton.Value = true;
            app.MidpointMethodButton = uiradiobutton(app.ApproximationMethodButtonGroup);
            app.MidpointMethodButton.Text = 'Midpoint Method';
            app.hEditFieldLabel = uilabel(app.InputParametersPanel);
            app.hEditFieldLabel.Text = 'h =';
            app.hEditField = uieditfield(app.InputParametersPanel, 'text');
            app.x0Label = uilabel(app.InputParametersPanel);
            app.x0Label.Text = 'x0 =';
            app.x0EditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.yx0EditFieldLabel = uilabel(app.InputParametersPanel);
            app.yx0EditFieldLabel.Text = 'y(x0) =';
            app.yx0EditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.xnEditFieldLabel = uilabel(app.InputParametersPanel);
            app.xnEditFieldLabel.Text = 'xn =';
            app.xnEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.dydxEditFieldLabel = uilabel(app.InputParametersPanel);
            app.dydxEditFieldLabel.Text = 'dy/dx =';
            app.dydxEditField = uieditfield(app.InputParametersPanel, 'text');
            app.Label = uilabel(app.InputParametersPanel);
            app.Label.Text = 'Notes: Enter dy/dx in MATLAB anonymous function form, eg. @(x,y) x*exp(y). h may be a single value or  multiple';
            app.Label_2 = uilabel(app.InputParametersPanel);
            app.Label_2.Text = 'values to compare. To enter multiple values, separate them with spaces. If you check MATLAB Low Order';
            app.SolveButton = uibutton(app.InputParametersPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @solve, true);
            app.SolveButton.Text = 'Solve!';
            app.ClearPlotButton = uibutton(app.InputParametersPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @clear, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ComparetoMATLABLowOrderMethodCheckBox = uicheckbox(app.InputParametersPanel);
            app.ComparetoMATLABLowOrderMethodCheckBox.Text = 'Compare to MATLAB Low Order Method';
            app.Label_3 = uilabel(app.InputParametersPanel);
            app.Label_3.Text = 'Method, an exact solution will be plotted, the table''s "h Value" will be listed as -1, and other h vals are optional.';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Solution Curve(s)');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'h Value'; 'yn Approximation'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = StewartLA3()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end